classdef vrControlHabituationModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        rewardUnits                     matlab.ui.control.DropDown
        RewardUnitsDropDownLabel        matlab.ui.control.Label
        rewardSize                      matlab.ui.control.NumericEditField
        RewardSizeEditFieldLabel        matlab.ui.control.Label
        deliverReward                   matlab.ui.control.Button
        MovementSinceLastRewardDeliverycmLabel  matlab.ui.control.Label
        elapsedMovement                 matlab.ui.control.LinearGauge
        lickIndicator                   matlab.ui.control.Lamp
        LickIndicatorLabel              matlab.ui.control.Label
        positionLimit                   matlab.ui.control.NumericEditField
        MaxPositionLimitEditFieldLabel  matlab.ui.control.Label
        MousePositioncmGaugeLabel       matlab.ui.control.Label
        position                        matlab.ui.control.LinearGauge
        speed                           matlab.ui.control.LinearGauge
        MouseSpeedcmsGaugeLabel         matlab.ui.control.Label
        rewDeliveredSeconds             matlab.ui.control.UIAxes
        rewDeliveredUL                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        rigInfo
        lickActive % Inherited from rigInfo
        currentPosition % Mouses current position on wheel (in cm)
        lastRewardPosition % Position of last reward delivery (in cm)
        elapsedLimit = 50;
        standardLimit = 50; 
    end
    
    methods (Access = public)
        
        function updatePosition(app, newPosition)
            app.currentPosition = newPosition;
            app.position.Value = mod(newPosition, app.position.Limits(2)); 
            updateElapsedMovement(app)
        end

        function updateSpeed(app, newSpeed)
            app.speed.Value = newSpeed;
        end

        function updateLickIndicator(app, state)
            if app.lickActive
                if state
                    app.lickIndicator.Color = 'g';
                else
                    app.lickIndicator.Color = 'r';
                end
            else
                app.lickIndicator.Color = 'k';
            end
        end

    end

    methods (Access = private)

        function updateElapsedMovement(app)
            movementSinceLastReward = app.currentPosition - app.lastRewardPosition; 
            if abs(movementSinceLastReward) > app.elapsedLimit
                app.elapsedLimit = app.elapsedLimit*1.5;
                changeLimit = true; 
            elseif abs(movementSinceLastReward) < app.elapsedLimit/2
                app.elapsedLimit = app.elapsedLimit/2;
                changeLimit = true;
            elseif movementSinceLastReward==0
                app.elapsedLimit = app.standardLimit;
                changeLimit = true; 
            else
                changeLimit = false;
            end
            if changeLimit
                app.elapsedMovement.Limits = app.elapsedLimit * [-1, 1];
            end
            app.movementSinceLastReward.Value = movementSinceLastReward; 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rigInfo = vrControlRigParameters();
            app.UIFigure.Position([1 2]) = app.rigInfo.localFigurePosition; 
            app.lickActive = app.rigInfo.lickEncoderAvailable;
        end

        % Value changed function: positionLimit
        function positionLimitValueChanged(app, event)
            app.position.Limits = [0 app.positionLimit.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.rewDeliveredUL = uiaxes(app.UIFigure);
            ylabel(app.rewDeliveredUL, 'Reward Delivered (ul)')
            zlabel(app.rewDeliveredUL, 'Z')
            app.rewDeliveredUL.XLim = [0 2];
            app.rewDeliveredUL.YLim = [0 10];
            app.rewDeliveredUL.XTick = [];
            app.rewDeliveredUL.XTickLabel = '';
            app.rewDeliveredUL.YTick = [0 5 10];
            app.rewDeliveredSeconds = uiaxes(app.UIFigure);
            ylabel(app.rewDeliveredSeconds, 'Reward Delivered (s)')
            zlabel(app.rewDeliveredSeconds, 'Z')
            app.rewDeliveredSeconds.XLim = [0 2];
            app.rewDeliveredSeconds.YLim = [0 10];
            app.rewDeliveredSeconds.XTick = [];
            app.rewDeliveredSeconds.XTickLabel = '';
            app.rewDeliveredSeconds.YTick = [0 5 10];
            app.MouseSpeedcmsGaugeLabel = uilabel(app.UIFigure);
            app.MouseSpeedcmsGaugeLabel.Text = 'Mouse Speed (cm/s)';
            app.speed = uigauge(app.UIFigure, 'linear');
            app.speed.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.position = uigauge(app.UIFigure, 'linear');
            app.position.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.MousePositioncmGaugeLabel = uilabel(app.UIFigure);
            app.MousePositioncmGaugeLabel.Text = 'Mouse Position (cm)';
            app.MaxPositionLimitEditFieldLabel = uilabel(app.UIFigure);
            app.MaxPositionLimitEditFieldLabel.Text = 'Max Position Limit';
            app.positionLimit = uieditfield(app.UIFigure, 'numeric');
            app.positionLimit.Limits = [1 Inf];
            app.positionLimit.ValueChangedFcn = createCallbackFcn(app, @positionLimitValueChanged, true);
            app.positionLimit.Value = 100;
            app.LickIndicatorLabel = uilabel(app.UIFigure);
            app.LickIndicatorLabel.Text = 'Lick Indicator';
            app.lickIndicator = uilamp(app.UIFigure);
            app.elapsedMovement = uigauge(app.UIFigure, 'linear');
            app.elapsedMovement.Limits = [-50 50];
            app.elapsedMovement.MajorTicks = [-50 -40 -30 -20 -10 0 10 20 30 40 50];
            app.MovementSinceLastRewardDeliverycmLabel = uilabel(app.UIFigure);
            app.MovementSinceLastRewardDeliverycmLabel.Text = 'Movement Since Last Reward Delivery (cm)';
            app.deliverReward = uibutton(app.UIFigure, 'push');
            app.deliverReward.Text = 'Deliver Reward';
            app.RewardSizeEditFieldLabel = uilabel(app.UIFigure);
            app.RewardSizeEditFieldLabel.Text = 'Reward Size';
            app.rewardSize = uieditfield(app.UIFigure, 'numeric');
            app.rewardSize.Limits = [0 2];
            app.rewardSize.Value = 0.2;
            app.RewardUnitsDropDownLabel = uilabel(app.UIFigure);
            app.RewardUnitsDropDownLabel.Text = 'Reward Units';
            app.rewardUnits = uidropdown(app.UIFigure);
            app.rewardUnits.Items = {'s', 'ul'};
            app.rewardUnits.Value = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vrControlHabituationModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end